classdef app1_postprocessingfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RealtimeEMGDataProcessingUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        FileSelectionPanel            matlab.ui.container.Panel
        DataloadedLabel               matlab.ui.control.Label
        SelectFileButton              matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        EMGPostProcessingToolLabel    matlab.ui.control.Label
        EMGChannelsPanel              matlab.ui.container.Panel
        TriggerchannelDropDown        matlab.ui.control.DropDown
        TriggerchannelDropDownLabel   matlab.ui.control.Label
        EMGinputchannelDropDownLabel  matlab.ui.control.Label
        EMGinputchannelDropDown       matlab.ui.control.DropDown
        AnalysisParametersPanel       matlab.ui.container.Panel
        Marker2                       matlab.ui.control.Spinner
        andLabel                      matlab.ui.control.Label
        Marker1                       matlab.ui.control.Spinner
        PlotwindowmsLabel_2           matlab.ui.control.Label
        UpdateplotseveryEditField     matlab.ui.control.NumericEditField
        UpdateplotseveryEditField_2Label  matlab.ui.control.Label
        TotalruntimesEditField        matlab.ui.control.NumericEditField
        TotalruntimesLabel            matlab.ui.control.Label
        PlotWindow2                   matlab.ui.control.Spinner
        toSpinnerLabel                matlab.ui.control.Label
        PlotWindow1                   matlab.ui.control.Spinner
        PlotwindowmsLabel             matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        seconds1                      matlab.ui.control.Label
        ControlPanel                  matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        INPROGRESSLabel               matlab.ui.control.Label
        ProcessingLamp                matlab.ui.control.Lamp
        SaveGraphButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        fullPath         % File location
        emg_window_data  % EMG data
        Input_error      % Alert for input errors
        RunTimeValue     % Run time user input
        PlotWindowValue1 % Lower limit of plot window
        PlotWindowValue2 % Upper limit of plot window
        UpdatePlots      % Update plots user input
        EMGinput         % EMG input channel user input 
        EMGtrigger       % EMG trigger channel user input
        TestingMode      % Flag to determine testing mode

    end 

    methods (Access = private)
        %% Error checking function
        function checkInputFields(app)
            errorMessage = "";
            
            % Check Run Time input
            app.RunTimeValue = app.TotalruntimesEditField.Value; 
            if isempty(app.RunTimeValue) || isnan(app.RunTimeValue) || app.RunTimeValue < 1 || app.RunTimeValue > 100 || floor(app.RunTimeValue) ~= app.RunTimeValue
                errorMessage = append(errorMessage, "Total run time. ");
            end
            
            % Check Plot Window input
            if app.PlotWindow1.Value > app.PlotWindow2.Value
                errorMessage = append(errorMessage, "Plot window: value 1 must be lower than value 2. ");
            end
            
            % Check Update Plots value
            app.UpdatePlots = app.UpdateplotseveryEditField.Value;
            if isempty(app.UpdatePlots) || isnan(app.UpdatePlots) || app.UpdatePlots < 1 || app.UpdatePlots > 100 || floor(app.UpdatePlots) ~= app.UpdatePlots
                errorMessage = append(errorMessage, "Plot update timeframe. ");
            end
            
            % Display the error message if any
            if errorMessage ~= ""
                errorText = ["Please enter valid inputs for: ", errorMessage];
                uialert(app.RealtimeEMGDataProcessingUIFigure, errorText, 'Invalid Input');
            end
        end
        
        %% EMG Channel Checking Function
        function checkEMGChannelFields(app)
            errorMessageEMG = "";
            app.EMGinput = app.EMGinputchannelDropDown.Value;
            app.EMGtrigger = app.TriggerchannelDropDown.Value;

            if sum(emgDataArray{i_set,1}(:,app.EMGinput)) == 0      % sum of the column is 0 to check if channel is in use
                errorMessageEMG = append(errorMessageEMG, "There is no signal from the selected EMG input channel. ");
            end

            if sum(emgDataArray{i_set,1}(:,app.EMGtrigger)) == 0      % sum of the column is 0 to check if channel is in use
                errorMessageEMG = append(errorMessageEMG, "There is no signal from the selected EMG trigger channel. ");
            end

            % Display the error message if any
            if errorMessage ~= ""
                errorTextEMG = ["Please reselect EMG channels: ", errorMessageEMG];
                uialert(app.RealtimeEMGDataProcessingUIFigure, errorTextEMG, 'EMG Input Error');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app2(app.emg_window_data);
            app.ProcessingLamp.Visible = 'off';
            app.INPROGRESSLabel.Visible = 'off';
            return                                            % Programmatically stop execution
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% 1 - Initialise Variables
            app.checkInputFields()                            % Calls error checking function
            
            disp(app.fullPath)
            %load('EgData2.mat');                              % Testing
            load(app.fullPath)
            
            % Turn on lamp
            app.ProcessingLamp.Visible = 'on';
            app.INPROGRESSLabel.Visible = 'on';
            % Disable inputs once program begins to run
            app.SelectFileButton.Enable = "off";
            app.TotalruntimesEditField.Enable = "off";
            app.PlotWindow1.Enable = "off";
            app.PlotWindow2.Enable = "off";
            app.UpdateplotseveryEditField.Enable = "off";
            app.Marker1.Enable = "off";
            app.Marker2.Enable = "off";
            app.EMGinputchannelDropDown.Enable = "off";
            app.TriggerchannelDropDown.Enable = "off";
            app.StartButton.Enable = "off";

            AnalogFrameRate = 2222;                                          % Sampling frequency
            app.RunTimeValue = app.TotalruntimesEditField.Value; 
            app.UpdatePlots = app.UpdateplotseveryEditField.Value;
            TriggerEMG = str2double(app.TriggerchannelDropDown.Value);       % String to number
            InputEMG = str2double(app.EMGinputchannelDropDown.Value);
            Marker_1 = app.Marker1.Value;
            Marker_2 = app.Marker2.Value;
            Time = app.PlotWindow1.Value:1/2222*1000 : app.PlotWindow2.Value;     

            TotalPlots = app.RunTimeValue/app.UpdatePlots;                   % Runtime/segment length
            
            count = 0;
            stimulation_data = [];
            emg_window_data_array = [];
            temp = [];

            for i_set = 1:(TotalPlots)
                %% 1 - Identifying trigger onsets

                negative_emg_signal = -emgDataArray{i_set,1}(:,(TriggerEMG));     % Extract the negative EMG signal from the data
                min_emg = min(negative_emg_signal);                    % Calculate minimum values of the negative EMG signal
                max_emg = max(negative_emg_signal);                    % Calculate maximum values of the negative EMG signal
                min_peak_height = min_emg + (max_emg - min_emg) / 2;   % Calculate minimum peak height as midpoint between min and max
                % Finding peaks in the negative EMG signal
                [~, StimFrames] = findpeaks(negative_emg_signal, 'MinPeakHeight', min_peak_height, 'MinPeakDistance', AnalogFrameRate);
                
                %% 2 - Extract and plot stimulations
               
                for i_stim = 1:size(StimFrames,1)                               % For all stimulation frames
                    count = count + 1;
    
                    % Extracts EMG data in window of interest for current stimulation and appends to new column in matrix
                    emg_window_data_array(:,count) = emgDataArray{i_set, 1}(round(StimFrames(i_stim) + app.PlotWindow1.Value/1000*2222):round(StimFrames(i_stim) + app.PlotWindow2.Value/1000*2222), InputEMG);
                    min_value(:,count) = min(emg_window_data_array(:,count));         % Extract minimum values within window of interest
                    max_value(:,count) = max(emg_window_data_array(:,count));         % Extract maximum values within window of interest
                    pk_pk_amplitude(:,count) = max_value(:,count) - min_value(:,count);
                    currentpk_pk_amplitude = pk_pk_amplitude(count);
    
                    % Convert to string with exponent notation
                    exponent = floor(log10(currentpk_pk_amplitude));
                    coefficient = currentpk_pk_amplitude / (10^exponent);
                    amplitude_str_temp = sprintf('%g x 10^{%d}', coefficient, exponent);  
                    amplitude_str{count} = amplitude_str_temp;
                    clear amplitude_str_temp
                   
                    stimulation_data = [stimulation_data, emg_window_data_array];  % Store the EMG data for the current stimulation
                    average_emg_data = mean(emg_window_data_array,2);              % Average line of all stimulations
                    subtitle(app.UIAxes,[num2str(count) ' Stimulations'])
             
                end

                cla(app.UIAxes)    % Clear axes
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, Time, emg_window_data_array); 
                Average_label = ['Average of ', num2str(count) ' Stims'];
                plot(app.UIAxes, Time, average_emg_data, 'r', 'LineWidth', 2);      % Plot average line 
                xline(app.UIAxes, [Marker_1 Marker_2],'color',[0.5 0.5 0.5],'linestyle',':', 'LineWidth', 1.2, 'DisplayName', '') % Line marker at 18ms and 35ms post-stimulation
                xline(app.UIAxes, 0, 'LineWidth', 1);

                hold(app.UIAxes, 'off')

                legendInfo = legend(app.UIAxes, {amplitude_str{1:end} Average_label}, 'Location', 'southoutside');
                legendInfo.NumColumns = 4;
                legendInfo.Title.String = 'MEP Amplitude';
                pause(3)          % to simulate real-time output

            end
            
            app.emg_window_data = emg_window_data_array;
            % Turn off lamp
            app.ProcessingLamp.Color = 'green';
            app.INPROGRESSLabel.Text = 'COMPLETE';

            close all;
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.mat', 'MATLAB Files (*.mat)'}, 'Select a MATLAB file');
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User pressed cancel');
            else
                app.fullPath = fullfile(pathname, filename);
                app.emg_window_data = app.fullPath;
                load(app.fullPath);
                app.TestingMode = true;
                app.DataloadedLabel.Visible = 'on';
            end
            
        end

        % Button pushed function: SaveGraphButton
        function SaveGraphButtonPushed(app, event)
            % Generate a timestamp string; format 30 gives date-time string suitable for filenames (yyyymmddTHHMMSS)
            timestamp = datestr(now,'dd.mm.yy_HH.MM.SS');
            % Create filename using the timestamp
            filename = ['MEP' timestamp '.png'];
            exportgraphics(app.UIAxes, filename);
            
            % Display a message to the user
            uialert(app.RealtimeEMGDataProcessingUIFigure, ['Graph saved as ' filename], 'Save Successful');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);  % Clears plot
            app.UIAxes.Subtitle.String = ''; % Clear the subtitle
            legend(app.UIAxes, 'off');       % Clear the legend
            app.DataloadedLabel.Visible = 'off';
           
            % Reset lamp
            app.ProcessingLamp.Color = 'yellow';
            app.INPROGRESSLabel.Text = 'IN PROGRESS';
            app.INPROGRESSLabel.Visible = 'off';
            app.ProcessingLamp.Visible = 'off';
            % Enable all inputs again
            app.SelectFileButton.Enable = "on";
            app.TotalruntimesEditField.Enable = "on";
            app.PlotWindow1.Enable = "on";
            app.PlotWindow2.Enable = "on";
            app.UpdateplotseveryEditField.Enable = "on";
            app.Marker1.Enable = "on";
            app.Marker2.Enable = "on";
            app.EMGinputchannelDropDown.Enable = "on";
            app.TriggerchannelDropDown.Enable = "on";
            app.StartButton.Enable = "on";
        end

        % Value changed function: PlotWindow2
        function PlotWindow2ValueChanged(app, event)
            % Check Plot Window input
            if app.PlotWindow1.Value > app.PlotWindow2.Value
                app.Label.Text = 'Invalid Input: Value 1 must be smaller than value 2.';
                app.Label.Visible = "on"; % Display an error message
            else
                % If the input is valid, clear the error message or take other actions
                app.Label.Text = '';
                app.Label.Visible = 'off';
            end

        end

        % Value changed function: TotalruntimesEditField
        function TotalruntimesEditFieldValueChanged(app, event)
            app.RunTimeValue = app.TotalruntimesEditField.Value; 
            
             % Check if the input is NaN (not a number) or if it's outside the valid range
            if isempty(app.RunTimeValue) || isnan(app.RunTimeValue) || app.RunTimeValue < 1 || app.RunTimeValue > 100 || floor(app.RunTimeValue) ~= app.RunTimeValue
                % Update the Label to show an error message
                app.Label.Text = 'Invalid Input: Total run time.';
                app.Label.Visible = "on"; % Display an error message
            else
                % If the input is valid, clear the error message or take other actions
                app.Label.Text = '';
                app.Label.Visible = 'off';
            end
        end

        % Value changed function: UpdateplotseveryEditField
        function UpdateplotseveryEditFieldValueChanged(app, event)
            app.UpdatePlots = app.UpdateplotseveryEditField.Value; 

             % Check if the input is NaN (not a number) or if it's outside the valid range
            if isempty(app.UpdatePlots) || isnan(app.UpdatePlots) || app.UpdatePlots < 1 || app.UpdatePlots > 100 || floor(app.UpdatePlots) ~= app.UpdatePlots
                % Update the Label to show an error message
                app.Label.Text = 'Invalid Input: Plot update timeframe.';
                app.Label.Visible = "on"; % Display an error message
            else
                % If the input is valid, clear the error message or take other actions
                app.Label.Text = '';
                app.Label.Visible = 'off';
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RealtimeEMGDataProcessingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {685, 685};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {240, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RealtimeEMGDataProcessingUIFigure = uifigure('Visible', 'off');
            app.RealtimeEMGDataProcessingUIFigure.Name = 'Real-time EMG Data Processing';
            app.RealtimeEMGDataProcessingUIFigure.Icon = '2.png';
            app.RealtimeEMGDataProcessingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RealtimeEMGDataProcessingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ControlPanel = uipanel(app.LeftPanel);
            app.ControlPanel.Title = '  Control Panel';
            app.StartButton = uibutton(app.ControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = fullfile(pathToMLAPP, '2noun-play-1939987.png');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, '2noun-stop-2124143.png');
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.ControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'refresh.jpg');
            app.ResetButton.Text = 'Reset';
            app.AnalysisParametersPanel = uipanel(app.LeftPanel);
            app.AnalysisParametersPanel.Title = 'Analysis Parameters';
            app.seconds1 = uilabel(app.AnalysisParametersPanel);
            app.seconds1.Text = 's';
            app.Label = uilabel(app.AnalysisParametersPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.PlotwindowmsLabel = uilabel(app.AnalysisParametersPanel);
            app.PlotwindowmsLabel.Text = 'Plot window (ms):';
            app.PlotWindow1 = uispinner(app.AnalysisParametersPanel);
            app.toSpinnerLabel = uilabel(app.AnalysisParametersPanel);
            app.toSpinnerLabel.Text = 'to';
            app.PlotWindow2 = uispinner(app.AnalysisParametersPanel);
            app.PlotWindow2.ValueChangedFcn = createCallbackFcn(app, @PlotWindow2ValueChanged, true);
            app.PlotWindow2.Value = 60;
            app.TotalruntimesLabel = uilabel(app.AnalysisParametersPanel);
            app.TotalruntimesLabel.Text = 'Total run time (s):';
            app.TotalruntimesEditField = uieditfield(app.AnalysisParametersPanel, 'numeric');
            app.TotalruntimesEditField.Limits = [0 1800];
            app.TotalruntimesEditField.RoundFractionalValues = 'on';
            app.TotalruntimesEditField.AllowEmpty = 'on';
            app.TotalruntimesEditField.ValueChangedFcn = createCallbackFcn(app, @TotalruntimesEditFieldValueChanged, true);
            app.TotalruntimesEditField.Value = 30;
            app.UpdateplotseveryEditField_2Label = uilabel(app.AnalysisParametersPanel);
            app.UpdateplotseveryEditField_2Label.Text = 'Update plots every:';
            app.UpdateplotseveryEditField = uieditfield(app.AnalysisParametersPanel, 'numeric');
            app.UpdateplotseveryEditField.Limits = [3 50];
            app.UpdateplotseveryEditField.RoundFractionalValues = 'on';
            app.UpdateplotseveryEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateplotseveryEditFieldValueChanged, true);
            app.UpdateplotseveryEditField.Value = 10;
            app.PlotwindowmsLabel_2 = uilabel(app.AnalysisParametersPanel);
            app.PlotwindowmsLabel_2.Text = 'Marker Positions (ms):';
            app.Marker1 = uispinner(app.AnalysisParametersPanel);
            app.Marker1.RoundFractionalValues = 'on';
            app.Marker1.Value = 18;
            app.andLabel = uilabel(app.AnalysisParametersPanel);
            app.andLabel.Text = '&';
            app.Marker2 = uispinner(app.AnalysisParametersPanel);
            app.Marker2.RoundFractionalValues = 'on';
            app.Marker2.Value = 35;
            app.EMGChannelsPanel = uipanel(app.LeftPanel);
            app.EMGChannelsPanel.Title = '  EMG Channels';
            app.EMGinputchannelDropDown = uidropdown(app.EMGChannelsPanel);
            app.EMGinputchannelDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.EMGinputchannelDropDown.Value = '1';
            app.EMGinputchannelDropDownLabel = uilabel(app.EMGChannelsPanel);
            app.EMGinputchannelDropDownLabel.Text = 'EMG input channel';
            app.TriggerchannelDropDownLabel = uilabel(app.EMGChannelsPanel);
            app.TriggerchannelDropDownLabel.Text = 'Trigger channel';
            app.TriggerchannelDropDown = uidropdown(app.EMGChannelsPanel);
            app.TriggerchannelDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.TriggerchannelDropDown.Value = '5';
            app.EMGPostProcessingToolLabel = uilabel(app.LeftPanel);
            app.EMGPostProcessingToolLabel.Text = 'EMG Post Processing Tool';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, '2.png');
            app.FileSelectionPanel = uipanel(app.LeftPanel);
            app.FileSelectionPanel.Title = 'File Selection';
            app.SelectFileButton = uibutton(app.FileSelectionPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.DataloadedLabel = uilabel(app.FileSelectionPanel);
            app.DataloadedLabel.Visible = 'off';
            app.DataloadedLabel.Text = 'Data loaded.';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Motor Evoked Potentials')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'EMG (mV)')
            zlabel(app.UIAxes, 'Z')
            app.SaveGraphButton = uibutton(app.RightPanel, 'push');
            app.SaveGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButtonPushed, true);
            app.SaveGraphButton.Icon = fullfile(pathToMLAPP, 'save-file.png');
            app.SaveGraphButton.Text = 'Save Graph';
            app.ProcessingLamp = uilamp(app.RightPanel);
            app.ProcessingLamp.Visible = 'off';
            app.INPROGRESSLabel = uilabel(app.RightPanel);
            app.INPROGRESSLabel.Visible = 'off';
            app.INPROGRESSLabel.Text = 'IN PROGRESS';
            % Show the figure after all components are created
            app.RealtimeEMGDataProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_postprocessingfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RealtimeEMGDataProcessingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RealtimeEMGDataProcessingUIFigure)
        end
    end
end