classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RealTimeEMGProcessorUIFigure   matlab.ui.Figure
        DontSaveButton                 matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        StimulationIntensityEditField  matlab.ui.control.EditField
        StimulationIntensityLabel      matlab.ui.control.Label
        ParticipantIDEditField         matlab.ui.control.EditField
        ParticipantIDLabel             matlab.ui.control.Label
        SavethecollecteddataLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        emg_window_data
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, emg_window_data)
            app.emg_window_data = emg_window_data;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            ID = app.ParticipantIDEditField.Value;
            StimIntensity = app.StimulationIntensityEditField.Value;

            if isempty(ID) || isempty(StimIntensity) % Check if either field is empty
                uialert(app.RealTimeEMGProcessorUIFigure, 'Please fill in all required fields.', 'Input Error', 'Icon', 'error'); % Display an error message
            else
                Save_Data = app.emg_window_data;
                saveLocation = uigetdir('', 'Select a location to save your file');
                filename = strcat('Participant', ID, '_', StimIntensity,'stim', '.mat');
                fileLocation = fullfile(saveLocation, filename);
                save(filename, 'Save_Data');  % Save the file to the current directory
                delete(app); 
            end
        end

        % Button pushed function: DontSaveButton
        function DontSaveButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RealTimeEMGProcessorUIFigure = uifigure('Visible', 'off');
            app.RealTimeEMGProcessorUIFigure.Name = 'Real-Time EMG Processor';
            app.RealTimeEMGProcessorUIFigure.Icon = fullfile(pathToMLAPP, 'save-file.png');
            app.SavethecollecteddataLabel = uilabel(app.RealTimeEMGProcessorUIFigure);
            app.SavethecollecteddataLabel.Text = '   Save the collected data?';
            app.ParticipantIDLabel = uilabel(app.RealTimeEMGProcessorUIFigure);
            app.ParticipantIDLabel.Text = 'Participant ID:';
            app.ParticipantIDEditField = uieditfield(app.RealTimeEMGProcessorUIFigure, 'text');
            app.StimulationIntensityLabel = uilabel(app.RealTimeEMGProcessorUIFigure);
            app.StimulationIntensityLabel.Text = 'Stimulation Intensity:';
            app.StimulationIntensityEditField = uieditfield(app.RealTimeEMGProcessorUIFigure, 'text');
            app.SaveButton = uibutton(app.RealTimeEMGProcessorUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DontSaveButton = uibutton(app.RealTimeEMGProcessorUIFigure, 'push');
            app.DontSaveButton.ButtonPushedFcn = createCallbackFcn(app, @DontSaveButtonPushed, true);
            app.DontSaveButton.Text = 'Don''t Save';
            % Show the figure after all components are created
            app.RealTimeEMGProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RealTimeEMGProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RealTimeEMGProcessorUIFigure)
        end
    end
end